classdef gui_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        filenameEditField             matlab.ui.control.EditField
        filenameEditFieldLabel        matlab.ui.control.Label
        falsedataupperlimitEditField  matlab.ui.control.NumericEditField
        falsedataupperlimitEditFieldLabel  matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        LowerLimitfrequencyEditField  matlab.ui.control.NumericEditField
        LowerLimitfrequencyEditFieldLabel  matlab.ui.control.Label
        UpperLimitfrequencyEditField  matlab.ui.control.NumericEditField
        UpperLimitfrequencyEditFieldLabel  matlab.ui.control.Label
        falsedatalowerlimitEditField  matlab.ui.control.NumericEditField
        falsedatalowerlimitEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            input_l1 = app.falsedatalowerlimitEditField.Value;
            input_u1 = app.falsedataupperlimitEditField.Value;
            upper_limit = app.UpperLimitfrequencyEditField.Value;
            lower_limit = app.LowerLimitfrequencyEditField.Value;
            threshold = app.ThresholdEditField.Value;
            file_name = app.filenameEditField.Value;
            [T1,XT1,YT1,IT1] = test_run(input_l1,input_u1,upper_limit,lower_limit,threshold, file_name);
            
            plot(app.UIAxes,[0 1],[upper_limit upper_limit],'r')
            hold on
            plot(app.UIAxes,[0 1],[lower_limit lower_limit],'r')
            plot(app.UIAxes,T1,YT1(:,1),'g')
            %title(app.UIAxes,'Frequency');
            %xlabel(app.UIAxes,'Time');
            %ylabel(app.UIAxes,'Frequency');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frequency')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'frequency gen1')
            zlabel(app.UIAxes, 'Z')
            app.falsedatalowerlimitEditFieldLabel = uilabel(app.UIFigure);
            app.falsedatalowerlimitEditFieldLabel.Text = 'false-data lower limit';
            app.falsedatalowerlimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperLimitfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.UpperLimitfrequencyEditFieldLabel.Text = 'Upper - Limit frequency';
            app.UpperLimitfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperLimitfrequencyEditField.Value = 60.3;
            app.LowerLimitfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.LowerLimitfrequencyEditFieldLabel.Text = 'Lower - Limit  frequency';
            app.LowerLimitfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerLimitfrequencyEditField.Value = 59.7;
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Value = 40;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.falsedataupperlimitEditFieldLabel = uilabel(app.UIFigure);
            app.falsedataupperlimitEditFieldLabel.Text = 'false-data upper limit';
            app.falsedataupperlimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.falsedataupperlimitEditField.Value = 4;
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.filenameEditField.Value = '''n1_cp_2_a_3_copy''';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end